classdef Kamieniak_Rzezniewska < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure           % UI Figure
        Wykres             matlab.ui.control.UIAxes   % TRAF W DZIURE
        Start              matlab.ui.control.Button   % START
        LabelSlider        matlab.ui.control.Label    % PRĘDKOŚĆ POCZĄTKOWA
        PredkoscPoczatkowa matlab.ui.control.Slider   % [0 100]
        Zakoncz            matlab.ui.control.Button   % ZAKONCZ
        LabelSpinner       matlab.ui.control.Label    % KĄT RZUTU
        KatRzutu           matlab.ui.control.Spinner  % [0 90]
        LabelListBox       matlab.ui.control.Label    % POZIOM
        Poziom             matlab.ui.control.ListBox  % Poziom 1, Poziom 2,...
        Restart            matlab.ui.control.Button   % RESTART
        Continue           matlab.ui.control.Button   % GRAJ DALEJ
        LabelSpinner2      matlab.ui.control.Label    % PUNKTY
        Punkty             matlab.ui.control.Spinner  % [-Inf Inf]
        Opor               matlab.ui.control.CheckBox % OPÓR POWIETRZA
        LabelSpinner3      matlab.ui.control.Label    % WSPÓŁCZYNNIK
        Wspolczynnik       matlab.ui.control.Spinner  % [0 Inf]
    end


    methods (Access = private)
        
        function updatePlot(app)
                v0=app.PredkoscPoczatkowa.Value;
                alfa=app.KatRzutu.Value*pi/180;
                g=10;
                vx=cos(alfa)*v0;
                vy=sin(alfa)*v0;
                e=2.73;
                k=app.Wspolczynnik.Value;
                t=[0:0.01:5];
            
            if app.Opor.Value==1
            %rzut z oporem powietrza
                x=vx*(1-e.^((-1)*k*t))/k;
                y=(vy/k)+(g/(k^2))-(g.*t/k)-((k*vy+g)*e.^((-1)*k*t)/(k^2));
                l=length(x);
            else
            %normalny rzut (parabola)
                l=200;
                x = linspace(0,9,100);
                y=x.*tan(alfa)-10*x.^2/(2*v0^2*cos(alfa)^2);
           
            end

            plot(app.Wykres,x,y)
            
            if app.Opor.Value==1
                x=vx*(1-e.^((-1)*k*t))/k;
                y=(vy/k)+(g/(k^2))-(g.*t/k)-((k*vy+g)*e.^((-1)*k*t)/(k^2));
                l=length(x);
                for l=1:l
                    if x(l)>=8 && x(l)<8.1  %przyblizona wartosc w pkt 8
                        j=y(l);
                        break;
                    else
                        j=0;
                        continue;
                    end
                end
                l=length(x);
                for l=1:l
                    if x(l)>=9 && x(l)<9.1 
                        q=y(l);
                        break
                    else
                        q=0;
                        continue;
                    end

                end
                y1=q;
                y2=j;
                    
            else
                %normalny rzut (parabola)
              y1=9*tan(alfa)-10*(9^2)/(2*v0^2*cos(alfa)^2);
              y2=8*tan(alfa)-10*(8^2)/(2*v0^2*cos(alfa)^2);
            end

            
            switch app.Poziom.Value 
                
                case 'Poziom 1'
                    %y1=9*tan(alfa)-10*(9^2)/(2*v0^2*cos(alfa)^2);
                    if y1>4 && y1<6
                        text(app.Wykres,5,7,'Gratki pkt+1! ',...
                            'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                        pause(0.08);
                        app.Continue.Visible='on';
                        app.Start.Visible='off';
                        app.Punkty.Value=app.Punkty.Value+1;
                        app.Poziom.Value='Poziom 2';
                        app.Continue.Text='POZIOM 2';  %przejscie do poziomu 2
                    else
                        pkt=app.Punkty.Value;
                        napis=['Koniec gry! Twój wynik: ',num2str(pkt)];
                        text(app.Wykres,5,7,napis,...
                            'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                        pause(0.08);

                        app.Restart.Visible='on';
                        app.Start.Visible='off';
                        app.Punkty.Value=0;
                    end
                    
                case 'Poziom 2'
                    %y1=9*tan(alfa)-10*(9^2)/(2*v0^2*cos(alfa)^2);
                    %y2=8*tan(alfa)-10*(8^2)/(2*v0^2*cos(alfa)^2);
                    if y2>5 && y2<7
                        if y1>4 && y1<6
                            text(app.Wykres,5,7,'Gratki pkt+2! ',...
                                'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                            pause(0.08);
                            app.Continue.Visible='on';
                            app.Start.Visible='off';
                            app.Punkty.Value=app.Punkty.Value+2;
                            app.Poziom.Value='Poziom 3';
                            app.Continue.Text='POZIOM 3';  %przejscie do poziomu 3
                        else
                        pkt=app.Punkty.Value;
                        napis=['Koniec gry! Twój wynik: ',num2str(pkt)];                            
                        text(app.Wykres,5,7,napis,...
                            'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                        pause(0.08);

                        app.Restart.Visible='on';
                        app.Start.Visible='off';
                        app.Punkty.Value=0;
                        end
                    else
                        pkt=app.Punkty.Value;
                        napis=['Koniec gry! Twój wynik: ',num2str(pkt)];                        
                        text(app.Wykres,5,7,napis,...
                            'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                        pause(0.08);

                        app.Restart.Visible='on';
                        app.Start.Visible='off';
                        app.Punkty.Value=0;
                    end      
                     
                case 'Poziom 3'
                    y1=@(x)x*tan(alfa)-10*(x^2)/(2*v0^2*cos(alfa)^2);
                    x=[8:0.1:9];
                    if (y1(x)>2 && y1(x)<4) || (y1(x)>6 && y1(x)<8)
                        text(app.Wykres,5,7,'Gratki pkt+3! ',...
                                'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                         pause(0.08);
                         app.Continue.Visible='on';
                         app.Start.Visible='off';
                         app.Punkty.Value=app.Punkty.Value+3;
                         app.Poziom.Value='Poziom 4';
                         app.Continue.Text='POZIOM 4';
                    else
                        pkt=app.Punkty.Value;
                        napis=['Koniec gry! Twój wynik: ',num2str(pkt)];                        
                        text(app.Wykres,5,7,napis,...
                            'FontSize',30,'FontWeight','Bold','HorizontalAlignment','Center','Color','k');
                        pause(0.08);

                        app.Restart.Visible='on';
                        app.Start.Visible='off';
                        app.Punkty.Value=0;
                    end
                        
             end            


        end
        
        function updateWall(app)
            switch app.Poziom.Value 
                case 'Poziom 1'
                    line(app.Wykres,[9 9],[0 4]);
                    line(app.Wykres,[9 9],[6 10]);
                    hold(app.Wykres,'on');
                case 'Poziom 2'
                    line(app.Wykres,[9 9],[0 4]);
                    line(app.Wykres,[9 9],[6 10]);
                    line(app.Wykres,[8 8],[0 5]);
                    line(app.Wykres,[8 8],[7 10]);
                    hold(app.Wykres,'on');                   
                case 'Poziom 3'
                    %rectangle(app.Wykres,'Position',[8 0 1 2]);
                    %rectangle(app.Wykres,'Position',[8 4 1 2]);
                    %rectangle(app.Wykres,'Position',[8 8 1 2]);
                    hold(app.Wykres,'on');
            end    
        end
        
        function updateKat(app)
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Start.Visible='on';
            app.Restart.Visible='off';
            app.Continue.Visible='off';
            app.KatRzutu.Value=60;
            app.PredkoscPoczatkowa.Value=10;
            
            axis(app.Wykres,[0 10 0 10])
            updateWall(app)           
        end

        % Zakoncz button pushed function
        function ZakonczButtonPushed(app)
            closereq
        end

        % Start button pushed function
        function StartButtonPushed(app)
            updatePlot(app);
            
        end

        % Restart button pushed function
        function RestartButtonPushed(app)
            app.Restart.Visible='off';
            cla(app.Wykres);
            updateWall(app);
            app.Start.Visible='on';

        end

        % Continue button pushed function
        function ContinueButtonPushed(app)
            app.Continue.Visible='off';
            cla(app.Wykres);
            updateWall(app);
            app.Start.Visible='on';
        end

        % Poziom value changed function
        function PoziomValueChanged(app)
           cla(app.Wykres);
           updateWall(app);
        end

        % Opor value changed function
        function OporValueChanged(app)
            if app.Opor.Value==1
                app.Wspolczynnik.Visible='on';
                app.Wspolczynnik.Enable='on';
            else
                app.Wspolczynnik.Enable='off';
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Wykres = uiaxes(app.UIFigure);
            title(app.Wykres, 'TRAF W DZIURE');
            app.Wykres.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.Wykres.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed);
            app.Start.Text = 'START';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'PRĘDKOŚĆ POCZĄTKOWA';
            app.PredkoscPoczatkowa = uislider(app.UIFigure);
            app.Zakoncz = uibutton(app.UIFigure, 'push');
            app.Zakoncz.ButtonPushedFcn = createCallbackFcn(app, @ZakonczButtonPushed);
            app.Zakoncz.Text = 'ZAKONCZ';
            app.LabelSpinner = uilabel(app.UIFigure);
            app.LabelSpinner.Text = 'KĄT RZUTU';
            app.KatRzutu = uispinner(app.UIFigure);
            app.KatRzutu.Limits = [0 90];
            app.LabelListBox = uilabel(app.UIFigure);
            app.LabelListBox.Text = 'POZIOM';
            app.Poziom = uilistbox(app.UIFigure);
            app.Poziom.Items = {'Poziom 1', 'Poziom 2', 'Poziom 3', 'Poziom 4', 'Poziom 5'};
            app.Poziom.ValueChangedFcn = createCallbackFcn(app, @PoziomValueChanged);
            app.Poziom.Value = 'Poziom 1';
            app.Restart = uibutton(app.UIFigure, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed);
            app.Restart.Text = 'RESTART';
            app.Continue = uibutton(app.UIFigure, 'push');
            app.Continue.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed);
            app.Continue.Text = 'GRAJ DALEJ';
            app.LabelSpinner2 = uilabel(app.UIFigure);
            app.LabelSpinner2.Text = 'PUNKTY';
            app.Punkty = uispinner(app.UIFigure);
            app.Punkty.Enable = 'off';
            app.Opor = uicheckbox(app.UIFigure);
            app.Opor.ValueChangedFcn = createCallbackFcn(app, @OporValueChanged);
            app.Opor.Text = 'OPÓR POWIETRZA';
            app.Opor.Value = true;
            app.LabelSpinner3 = uilabel(app.UIFigure);
            app.LabelSpinner3.Text = 'WSPÓŁCZYNNIK';
            app.Wspolczynnik = uispinner(app.UIFigure);
            app.Wspolczynnik.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Kamieniak_Rzezniewska()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end